classdef gui_analysis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        GridLayout        matlab.ui.container.GridLayout
        PIEASMonogram     matlab.ui.control.Image
        COVIDInfectedRegionDetectionAnalysisusingCTLungsImagesLabel  matlab.ui.control.Label
        PRLabMonogram     matlab.ui.control.Image
        OriginalImage     matlab.ui.control.Image
        AnalysisButton    matlab.ui.control.Button
        UIAxes            matlab.ui.control.UIAxes
        PEACMonogram      matlab.ui.control.Image
        PAICMonogram      matlab.ui.control.Image
        CategoricalImage  matlab.ui.control.Image
        Label_2           matlab.ui.control.Label
    end

    
    properties (Access = public)
        original_image  % original image loaded by user
        segmentation_model
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, detection_app, original_image)
            fig = uifigure; d = uiprogressdlg(fig,'Title','Loading Segmentation Model','Indeterminate','on'); drawnow;
            load('trained-models/model-covid19-segmentation.mat','net_New_S_R');
            app.segmentation_model = net_New_S_R;
            disp(class(app.segmentation_model));
            disp(app.segmentation_model);
            delete(d); delete(fig);
            
            app.original_image = original_image;
            I = imshow("gui-images/image-placeholder.png", 'parent', app.UIAxes);
            set(I,'ButtonDownFcn',@buttondowncallback);

        end

        % Button pushed function: AnalysisButton
        function AnalysisButtonPushed(app, event)
            function buttondowncallback(app, objectHandle, eventData)
                coords = objectHandle.IntersectionPoint;
                x = int16(coords(1));
                y = int16(coords(2));
                selected_label = image_labels(y, x);
                if (selected_label > 0)
                    mask_indices = find(image_labels ~= selected_label);
                    intensity_factor = 0.25;
                    
                    rO = O(:,:,1); gO = O(:,:,2); bO = O(:,:,3);
                    rO(mask_indices) = rO(mask_indices) * intensity_factor;
                    gO(mask_indices) = gO(mask_indices) * intensity_factor;
                    bO(mask_indices) = bO(mask_indices) * intensity_factor;
                    O2 = cat(3,rO,gO,bO);
                    
                    rB = B(:,:,1); gB = B(:,:,2); bB = B(:,:,3);
                    rB(mask_indices) = rB(mask_indices) * intensity_factor;
                    gB(mask_indices) = gB(mask_indices) * intensity_factor;
                    bB(mask_indices) = bB(mask_indices) * intensity_factor;
                    B2 = cat(3,rB,gB,bB);
                else
                    O2 = O;
                    B2 = B;
                end
                appOI.ImageSource = O2;
                appCI.ImageSource = B2;
            end
            
            fig = uifigure; d = uiprogressdlg(fig,'Title','Running Segmentation Model','Indeterminate','on'); drawnow;
            [C,scores] = semanticseg(app.original_image,app.segmentation_model);
            delete(d); delete(fig);
            
            appOI = app.OriginalImage;
            appCI = app.CategoricalImage;
            O = app.original_image;
            B = labeloverlay(O,C);
            BW = C == 'COVID19';
            image_labels = bwlabel(BW);
            
            appOI.ImageSource = O;
            appCI.ImageSource = B;
            I = imshow(BW,'parent',app.UIAxes);
            set(I,'ButtonDownFcn',@buttondowncallback);

        end

        % Callback function
        function AnalyzedImageClicked(app, event)
            disp(event)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.PIEASMonogram = uiimage(app.GridLayout);
            app.PIEASMonogram.ImageSource = 'pieas-logo-black.jpg';
            app.COVIDInfectedRegionDetectionAnalysisusingCTLungsImagesLabel = uilabel(app.GridLayout);
            app.COVIDInfectedRegionDetectionAnalysisusingCTLungsImagesLabel.Text = {'COVID Infected Region Detection'; '& Analysis using CT Lungs Images'};
            app.PRLabMonogram = uiimage(app.GridLayout);
            app.PRLabMonogram.ImageSource = 'pr-lab-logo.jpg';
            app.OriginalImage = uiimage(app.GridLayout);
            app.OriginalImage.ImageSource = 'image-placeholder.png';
            app.AnalysisButton = uibutton(app.GridLayout, 'push');
            app.AnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @AnalysisButtonPushed, true);
            app.AnalysisButton.Text = 'Analysis';
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [];
            app.UIAxes.XTickLabel = {};
            app.UIAxes.YTick = [];
            app.UIAxes.YTickLabel = {};
            app.UIAxes.ZTick = [];
            app.UIAxes.ZTickLabel = '';
            app.PEACMonogram = uiimage(app.GridLayout);
            app.PEACMonogram.ImageSource = 'paec-logo.png';
            app.PAICMonogram = uiimage(app.GridLayout);
            app.PAICMonogram.ImageSource = 'paic-logo.jpg';
            app.CategoricalImage = uiimage(app.GridLayout);
            app.CategoricalImage.ImageSource = 'image-placeholder.png';
            app.Label_2 = uilabel(app.GridLayout);
            app.Label_2.Text = 'Saddam Hussain Khan, Asifullah Khan, Anabia Sohail, ,” Classification and Region Analysis of COVID-19 Infection using Lung CT Images and Deep Convolutional Neural Networks”, preprint, arXiv:2009.08864';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui_analysis(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end