classdef gui_detection < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        PAECMonogram               matlab.ui.control.Image
        PRLabMonogram              matlab.ui.control.Image
        COVIDInfectedRegionDetectionAnalysisusingCTLungsImagesLabel  matlab.ui.control.Label
        OriginalImagePanel         matlab.ui.container.Panel
        GridLayout2                matlab.ui.container.GridLayout
        LoadImageButton            matlab.ui.control.Button
        OriginalImage              matlab.ui.control.Image
        LoadFolderButton           matlab.ui.control.Button
        PrevButton                 matlab.ui.control.Button
        NextButton                 matlab.ui.control.Button
        PreProcessedImagePanel     matlab.ui.container.Panel
        GridLayout3                matlab.ui.container.GridLayout
        PreProcessingButton        matlab.ui.control.Button
        PreProcessedImage          matlab.ui.control.Image
        COVIDDetectionResultPanel  matlab.ui.container.Panel
        GridLayout4                matlab.ui.container.GridLayout
        DetectionButton            matlab.ui.control.Button
        DetectionLabel             matlab.ui.control.Label
        ProceedForAnalysisButton   matlab.ui.control.Button
        DetectionLabelTitle        matlab.ui.control.Label
        PIEASMonogram              matlab.ui.control.Image
        PAICMonogram               matlab.ui.control.Image
        Label                      matlab.ui.control.Label
    end

    
    properties (Access = public)
        original_image          % to store original image
        preprocessed_image      % image after dwt preprocessing
        images_path             % array to store path of images in folder that user has selected
        current_image_index     % index of images_path array
        classification_model
    end
    
    methods (Access = public)
        
        function next_prev_click_common(app)
            disp(app.current_image_index);
            current_image_path = app.images_path(app.current_image_index);
            disp(current_image_path{1});
            app.original_image = imread(current_image_path{1});
            disp(size(app.original_image));
            app.OriginalImage.ImageSource = app.original_image;
            app.PreProcessedImage.ImageSource = "gui-images/image-placeholder.png";
            app.DetectionButton.Enable = false;
            app.ProceedForAnalysisButton.Enable = false;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            fig = uifigure; d = uiprogressdlg(fig,'Title','Loading Classification Model','Indeterminate','on'); drawnow;
            load('trained-models/model-covid19-classification.mat','Custom_netresnet18_CT_Corona_DWT_W1');
            app.classification_model = Custom_netresnet18_CT_Corona_DWT_W1;
            delete(d); delete(fig);
        end

        % Button pushed function: LoadImageButton
        function LoadImageButtonPushed(app, event)
            [filename, filepath] = uigetfile({'*.jpg';'*.png'},'Select Image File');
            if class(filename) == "double"
                return;
            end
            app.original_image = imread([filepath, filename]);
            app.OriginalImage.ImageSource = app.original_image;
            app.PreProcessedImage.ImageSource = imread("gui-images/image-placeholder.png");
            app.PreProcessingButton.Enable = true;
            app.DetectionButton.Enable = false;
            app.ProceedForAnalysisButton.Enable = false;
            app.DetectionLabelTitle.Text = "";
            app.DetectionLabel.Text = "Click Detection Button";
            app.DetectionLabel.FontColor = [0 0 0];
        end

        % Button pushed function: LoadFolderButton
        function LoadFolderButtonPushed(app, event)
            dir_path = uigetdir('./','Select Image Folder');
            disp(dir_path);
            if class(dir_path) == "double"
                return;
            end
            names_list = dir(dir_path);
            names_list = names_list(3:end);
            for i = 1:size(names_list, 1)
                app.images_path{i} = sprintf('%s\\%s', dir_path, names_list(i).name);
            end
            app.current_image_index = 1;
            current_image_path = app.images_path(app.current_image_index);
            app.original_image = imread(current_image_path{1});
            app.OriginalImage.ImageSource = app.original_image;
            app.NextButton.Enable = true;
            app.PrevButton.Enable = true;
            app.PreProcessingButton.Enable = true;
        end

        % Button pushed function: PreProcessingButton
        function PreProcessingButtonPushed(app, event)
            I = app.original_image;
            signal1 = I(:,:,2);
            [cA1,cH1,cV1,cD1] = dwt2(signal1,'db4');
            
            [cA2,cH2,cV2,cD2] = dwt2(cA1,'db4');
            [cA3,cH3,cV3,cD3] = dwt2(cA2,'db4');
            
            [cA21,cH21,cV21,cD21] = dwt2(cD1,'db4');
            [cA31,cH31,cV31,cD31] = dwt2(cD2,'db4');
            
            DWT_feat = [cA3,cD3,cA31,cD31];
            X = idwt2(cA2,cH2,cV2,cD2,'db4');
            Third_Level_Decomposition1 = uint8(X);
            X1 = idwt2(cA21,cH21,cV21,cD21,'db4');
            Third_Level_Decomposition2 = uint8(X1);
            Third_Level_Decomposition1_final = Third_Level_Decomposition1 + Third_Level_Decomposition2;
            app.preprocessed_image = Third_Level_Decomposition1_final;
            Third_Level_Decomposition1_final = cat(3,Third_Level_Decomposition1_final,Third_Level_Decomposition1_final,Third_Level_Decomposition1_final);
            app.PreProcessedImage.ImageSource = Third_Level_Decomposition1_final;
            app.DetectionButton.Enable = true;
        end

        % Button pushed function: DetectionButton
        function DetectionButtonPushed(app, event)
            imdsTest2 = app.preprocessed_image;
            imdsTest2 = cat(3,imdsTest2,imdsTest2,imdsTest2);
            imdsTest2 = imresize(imdsTest2,[156 156]);
            
            fig = uifigure; d = uiprogressdlg(fig,'Title','Running Classification Model','Indeterminate','on'); drawnow;
            [predicted_labels,posterior] = classify(app.classification_model,imdsTest2);
            delete(d); delete(fig);
            
            fprintf("posterior: %f\n", posterior);
            A = categorical(predicted_labels);
            if(A == "Corona")
                app.DetectionLabelTitle.Text = "Dangerous";
                app.DetectionLabel.Text = """You are COVID-19 Infected""";
                app.DetectionLabelTitle.FontColor = [1 0 0];
                app.DetectionLabel.FontColor = [1 0 0];
                app.ProceedForAnalysisButton.Enable = true;
            else
                app.DetectionLabelTitle.Text = "Congratulations";
                app.DetectionLabel.Text = """You are Healthy""";
                app.DetectionLabelTitle.FontColor = [0 1 0];
                app.DetectionLabel.FontColor = [0 1 0];
                app.ProceedForAnalysisButton.Enable = false;
            end
        end

        % Button pushed function: ProceedForAnalysisButton
        function ProceedForAnalysisButtonPushed(app, event)
            gui_analysis(app, app.original_image);
        end

        % Button pushed function: PrevButton
        function PrevButtonPushed(app, event)
            app.current_image_index = mod(app.current_image_index - 1, size(app.images_path, 2));
            if (app.current_image_index == 0)
                app.current_image_index = size(app.images_path, 2);
            end
            next_prev_click_common(app);
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            app.current_image_index = mod(app.current_image_index + 1, size(app.images_path, 2));
            if (app.current_image_index == 0)
                app.current_image_index = 1;
            end
            next_prev_click_common(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.PAECMonogram = uiimage(app.GridLayout);
            app.PAECMonogram.ImageSource = 'paec-logo.png';
            app.PRLabMonogram = uiimage(app.GridLayout);
            app.PRLabMonogram.ImageSource = 'pr-lab-logo.jpg';
            app.COVIDInfectedRegionDetectionAnalysisusingCTLungsImagesLabel = uilabel(app.GridLayout);
            app.COVIDInfectedRegionDetectionAnalysisusingCTLungsImagesLabel.Text = {'COVID Infected Region Detection'; '& Analysis using CT Lungs Images'};
            app.OriginalImagePanel = uipanel(app.GridLayout);
            app.OriginalImagePanel.Title = 'Original Image';
            app.GridLayout2 = uigridlayout(app.OriginalImagePanel);
            app.LoadImageButton = uibutton(app.GridLayout2, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Text = 'Load Image';
            app.OriginalImage = uiimage(app.GridLayout2);
            app.OriginalImage.ImageSource = 'image-placeholder.png';
            app.LoadFolderButton = uibutton(app.GridLayout2, 'push');
            app.LoadFolderButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFolderButtonPushed, true);
            app.LoadFolderButton.Text = 'Load Folder';
            app.PrevButton = uibutton(app.GridLayout2, 'push');
            app.PrevButton.ButtonPushedFcn = createCallbackFcn(app, @PrevButtonPushed, true);
            app.PrevButton.Enable = 'off';
            app.PrevButton.Text = 'Prev';
            app.NextButton = uibutton(app.GridLayout2, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Enable = 'off';
            app.NextButton.Text = 'Next';
            app.PreProcessedImagePanel = uipanel(app.GridLayout);
            app.PreProcessedImagePanel.Title = 'Pre-Processed Image';
            app.GridLayout3 = uigridlayout(app.PreProcessedImagePanel);
            app.PreProcessingButton = uibutton(app.GridLayout3, 'push');
            app.PreProcessingButton.ButtonPushedFcn = createCallbackFcn(app, @PreProcessingButtonPushed, true);
            app.PreProcessingButton.Enable = 'off';
            app.PreProcessingButton.Text = 'Pre Processing';
            app.PreProcessedImage = uiimage(app.GridLayout3);
            app.PreProcessedImage.ImageSource = 'image-placeholder.png';
            app.COVIDDetectionResultPanel = uipanel(app.GridLayout);
            app.COVIDDetectionResultPanel.Title = 'COVID Detection Result';
            app.GridLayout4 = uigridlayout(app.COVIDDetectionResultPanel);
            app.DetectionButton = uibutton(app.GridLayout4, 'push');
            app.DetectionButton.ButtonPushedFcn = createCallbackFcn(app, @DetectionButtonPushed, true);
            app.DetectionButton.Enable = 'off';
            app.DetectionButton.Text = 'Detection';
            app.DetectionLabel = uilabel(app.GridLayout4);
            app.DetectionLabel.Tag = 'DetectionLabel';
            app.DetectionLabel.Text = 'Click Detection Button';
            app.ProceedForAnalysisButton = uibutton(app.GridLayout4, 'push');
            app.ProceedForAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @ProceedForAnalysisButtonPushed, true);
            app.ProceedForAnalysisButton.Enable = 'off';
            app.ProceedForAnalysisButton.Text = 'Proceed For Analysis';
            app.DetectionLabelTitle = uilabel(app.GridLayout4);
            app.DetectionLabelTitle.Text = '';
            app.PIEASMonogram = uiimage(app.GridLayout);
            app.PIEASMonogram.ImageSource = 'pieas-logo-black.jpg';
            app.PAICMonogram = uiimage(app.GridLayout);
            app.PAICMonogram.ImageSource = 'paic-logo.jpg';
            app.Label = uilabel(app.GridLayout);
            app.Label.Text = 'Saddam Hussain Khan, Asifullah Khan, Anabia Sohail, ,” Classification and Region Analysis of COVID-19 Infection using Lung CT Images and Deep Convolutional Neural Networks”, preprint, arXiv:2009.08864';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui_detection

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end